classdef Tower_Of_Hanoi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TowerofHanoiPanel            matlab.ui.container.Panel
        Label                        matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        ResetButton                  matlab.ui.control.Button
        FromPegToPegLabel            matlab.ui.control.Label
        FromTo                       matlab.ui.control.DropDown
        NumberofRingsEditFieldLabel  matlab.ui.control.Label
        NumRings                     matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global tower to From 
            colors = 'rgbymcwk';
            Num_rings = app.NumRings.Value;
            tower = struct('Peg1', {},'Peg2', {},'Peg3', {});
            for k = 1:Num_rings
                tower(1).Peg1{k} = struct('Size',Num_rings-k+1,'Color',colors(k));
            end
            tower.Peg2 = {};
            tower.Peg3 = {};
            display_tower_GUI(app.UIAxes, tower)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            startupFcn(app)
        end

        % Value changed function: FromTo
        function FromToValueChanged(app, event)
            global tower to from
            value = app.FromTo.Value;
 
            switch value
                case '1'
                    move_result = 0;
                case '2'
                    %peg 1 to 2
                    from = 1;
                    to = 2;
                case '3'
                    %peg 1 to 3
                    from = 1;
                    to = 3;
                case '4'
                    %peg 2 to 1
                    from = 2;
                    to = 1;
                case '5'
                    %peg 2 to 3
                    from = 2;
                    to = 3;
                case '6'
                    %peg 3 to 1
                    from = 3;
                    to = 1;
                case '7'
                    %peg 3 to 2
                    from = 3;
                    to = 2;
                case '8'
                    i = from;
                    from = to;
                    to = i;
            end
            
            if value ~= 1
                [tower,move_result] = move(tower, from, to)
                display_tower_GUI(app.UIAxes, tower)
            end
            if move_result == -1
               set(app.Label,'Text','Illegal Move.')
            elseif move_result == 1
                set(app.Label,'Text','You win!')
            else 
                set(app.Label,'Text',' ')
            end
            %insert move function down here probably?
        end

        % Value changed function: NumRings
        function NumRingsValueChanged(app, event)
              global tower to From 
            colors = 'rgbymcwk';
            Num_rings = app.NumRings.Value;
            tower = struct('Peg1', {},'Peg2', {},'Peg3', {});
            for k = 1:Num_rings
                tower(1).Peg1{k} = struct('Size',Num_rings-k+1,'Color',colors(k));
            end
            tower.Peg2 = {};
            tower.Peg3 = {};
            display_tower_GUI(app.UIAxes, tower)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TowerofHanoiPanel = uipanel(app.UIFigure);
            app.TowerofHanoiPanel.Title = 'Tower of Hanoi';
            app.Label = uilabel(app.TowerofHanoiPanel);
            app.Label.Text = '';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.FromPegToPegLabel = uilabel(app.UIFigure);
            app.FromPegToPegLabel.Text = 'From Peg / To Peg';
            app.FromTo = uidropdown(app.UIFigure);
            app.FromTo.Items = {'   ', '1 --> 2', '1 --> 3', '2 --> 1', '2 --> 3', '3 --> 1', '3 --> 2', 'Undo'};
            app.FromTo.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.FromTo.ValueChangedFcn = createCallbackFcn(app, @FromToValueChanged, true);
            app.FromTo.Value = '1';
            app.NumberofRingsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofRingsEditFieldLabel.Text = 'Number of Rings';
            app.NumRings = uieditfield(app.UIFigure, 'numeric');
            app.NumRings.ValueChangedFcn = createCallbackFcn(app, @NumRingsValueChanged, true);
            app.NumRings.Value = 4;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tower_Of_Hanoi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end